classdef PatternProjectGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PatternProjectGUIUIFigure       matlab.ui.Figure
        PatternRecognitionProjectLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        TrainingTab                     matlab.ui.container.Tab
        BrowseDatasetButton             matlab.ui.control.Button
        PleasewaitwhileTrainingLabel    matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ChooseanexecutiontypeButtonGroup  matlab.ui.container.ButtonGroup
        SequentialButton                matlab.ui.control.RadioButton
        ParallelButton                  matlab.ui.control.RadioButton
        SaveButton                      matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        EllapsedTimeforthisMethodLabel  matlab.ui.control.Label
        Lamp_2                          matlab.ui.control.Lamp
        ThanksforwaitingLabel           matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        FeaturestobeextractedButtonGroup  matlab.ui.container.ButtonGroup
        HOGButton                       matlab.ui.control.RadioButton
        CustomButton                    matlab.ui.control.RadioButton
        TrainButton                     matlab.ui.control.Button
        SegmentSizePanel                matlab.ui.container.Panel
        WidthEditFieldLabel             matlab.ui.control.Label
        SegmentWidthEditField           matlab.ui.control.NumericEditField
        HeightEditFieldLabel            matlab.ui.control.Label
        SegmentHeightEditField          matlab.ui.control.NumericEditField
        NoiseThresholdPanel             matlab.ui.container.Panel
        WidthEditField_2Label           matlab.ui.control.Label
        NoiseWidthEditField             matlab.ui.control.NumericEditField
        HeightEditField_2Label          matlab.ui.control.Label
        NoiseHeightEditField            matlab.ui.control.NumericEditField
        ClassificationTab               matlab.ui.container.Tab
        ChooseanalgorithmButtonGroup    matlab.ui.container.ButtonGroup
        WeightedkNNButton               matlab.ui.control.RadioButton
        kSpinnerLabel                   matlab.ui.control.Label
        kSpinner                        matlab.ui.control.Spinner
        EnterweightsSEPARATEDbycommaLabel  matlab.ui.control.Label
        WeightsLabel                    matlab.ui.control.Label
        WeightsEditField                matlab.ui.control.EditField
        SVMButton                       matlab.ui.control.RadioButton
        BayesianButton                  matlab.ui.control.RadioButton
        UIAxes                          matlab.ui.control.UIAxes
        BrowseImageButton               matlab.ui.control.Button
        ClassifyButton                  matlab.ui.control.Button
        XLabel                          matlab.ui.control.Label
        ViewlargerButton                matlab.ui.control.Button
        NoiseThresholdPanel2            matlab.ui.container.Panel
        NoiseWidthEditField_2Label      matlab.ui.control.Label
        NoiseWidthEditField2            matlab.ui.control.NumericEditField
        NoiseHeightEditField_2Label     matlab.ui.control.Label
        NoiseHeightEditField2           matlab.ui.control.NumericEditField
        PleasefirstyouMUSTgotoTrainingTabtoBrowsetheDatLabel  matlab.ui.control.Label
        EllapsedTimeforthisMethodLabel_2  matlab.ui.control.Label
        sLabel_2                        matlab.ui.control.Label
        EvaluationTab                   matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        BrowseTestsetButton             matlab.ui.control.Button
        EvaluateButton                  matlab.ui.control.Button
        NeuralNetworkButton             matlab.ui.control.Button
        PleasefirstyouMUSTgotoTrainingTabtoBrowsetheDatLabel_2  matlab.ui.control.Label
    end


    properties (Access = private)
        ir; % ImageReader Instance
        cl; % Classifier Instance
        doneSound; % Wave audio file
        % Shared Paths 
        DATASETPATH;
        TESTSETPATH;
        TESTIMGPATH;
        % Training Dataset Objects
        trainType;
        segmentSize;
        trainedSet;
        trainedSetClasses;
        trainedSVM;
        trainedBayes;
    end
    %This function made to hundle movements between the three radio buttons
    methods (Access = public)
        function RadioBtnGUI(app,kL, kS, kV, wL, wE, wV, wLL)
            app.kSpinner.Enable = kS;
            app.WeightsEditField.Enable = wE;
            app.WeightsLabel.Enable = wL;
            app.WeightsEditField.Value = wV;
            app.EnterweightsSEPARATEDbycommaLabel.Enable = wLL;
            app.kSpinnerLabel.FontColor = kL;
            app.kSpinner.Value = kV;
        end
        
        function WaitGUI(app)
            %app.BrowseDataset.Enable = 'off'
            %Delete Thanks Label and ConsumedTime Label (if still)
            app.Lamp_2.Visible = 'off'; %Green Lamp off
            app.ThanksforwaitingLabel.Visible = 'off';
            app.EllapsedTimeforthisMethodLabel.Visible = 'off';
            app.sLabel.Visible = 'off';
            
            app.Lamp.Visible = 'on'; %Red Lamp on
            app.PleasewaitwhileTrainingLabel.Visible = 'on';
            app.Label.Visible = 'on';
            app.Label.Text = '. . .';
        end
        
        function AfterWaitingGUI(app, timeConsumed)
            app.Lamp.Visible = 'off'; %Red Lamp off
            app.PleasewaitwhileTrainingLabel.Visible = 'off';
            app.Label.Visible = 'off'; %...
            
            app.Lamp_2.Visible = 'on'; %Green Lamp on
            app.ThanksforwaitingLabel.Visible = 'on';
            app.EllapsedTimeforthisMethodLabel.Visible = 'on';
            app.sLabel.Visible = 'on';
            if timeConsumed < 60
                timeConsumedSeconds = sprintf('%.2f', timeConsumed);
                app.sLabel.Text = strcat(num2str(timeConsumedSeconds),' sec');
            else
                timeConsumedMinutes = sprintf('%.2f', (timeConsumed/60)); 
                app.sLabel.Text = strcat(num2str(timeConsumedMinutes),' min');
            end
            
            %app.BrowseDataset.Enable = 'on'
            app.SaveButton.Enable = 'on';
            app.LoadButton.Enable = 'on';
            %Play sound
            sound(app.doneSound.y, app.doneSound.Fs);
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %clear all;
            close all;
            clc;
            app.ir = ImageReader;
            app.cl = Classifier;
            [app.doneSound.y, app.doneSound.Fs] = audioread('done.wav');
        end

        % Button pushed function: BrowseDatasetButton
        function BrowseDatasetButtonPushed(app, event)
            folderPath = uigetdir('Select dataset folder');
            if ~isequal(folderPath,0)
                app.DATASETPATH = folderPath;
                app.TrainButton.Enable = 'on';
            end
        end

        % Button pushed function: BrowseImageButton
        function BrowseImageButtonPushed(app, event)
            [fileName, pathName] = uigetfile({'*.jpg;*.tif;*.png;'},'Select an image');
            if ~isequal(fileName,0)
                app.TESTIMGPATH = strcat(pathName,fileName);
                testImg = imread(app.TESTIMGPATH);
                imshow(testImg, 'Parent', app.UIAxes);
                app.ClassifyButton.Enable = 'on';
                app.ViewlargerButton.Enable = 'on';
            end
        end

        % Selection changed function: ChooseanalgorithmButtonGroup
        function ChooseanalgorithmButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChooseanalgorithmButtonGroup.SelectedObject;
            
            if app.WeightedkNNButton.Value == true
                RadioBtnGUI(app,[0 0 0],'on',0,'on','on','0','on');
            end
            
            if app.BayesianButton.Value == true
                RadioBtnGUI(app,[0.6667 0.6667 0.6667],'off',0,'off','off','0','off');
            end
            
            if app.SVMButton.Value == true
                RadioBtnGUI(app,[0.6667 0.6667 0.6667],'off',0,'off','off','0','off');
            end
        end

        % Button pushed function: ClassifyButton
        function ClassifyButtonPushed(app, event)
            app.ClassifyButton.Enable = 'off';
            ClassifyButtonText = app.ClassifyButton.Text;
            app.ClassifyButton.Text = 'Please Wait...';
            app.EllapsedTimeforthisMethodLabel_2.Visible = 'off';
            app.sLabel_2.Visible = 'off';
            % reset image to original shape
            imshow(imread(app.TESTIMGPATH), 'Parent', app.UIAxes);
            pause(0.1); % wait for GUI to take effect
            
            noiseThreshold = app.NoiseWidthEditField2.Value*app.NoiseHeightEditField2.Value;
            tic; %start stopwatch
            if isequal(app.trainType, 'HOG')
                [testObjects, testObjectsPos] = app.cl.getImgReadyHOG(app.TESTIMGPATH, noiseThreshold, app.segmentSize);
            else%if isequal(app.trainType, 'Custom')
                [testObjects, testObjectsPos] = app.cl.getImgReady(app.TESTIMGPATH, noiseThreshold, app.segmentSize);
            end
            if app.WeightedkNNButton.Value == true
                k = app.kSpinner.Value;
                %Taking the text as a string then separating to chars (cell of chars)
                weightsStr = strsplit(app.WeightsEditField.Value,',');
                if numel(weightsStr) == size(app.trainedSet,2) || weightsStr{1} == '0'
                    try
                        %Converting each element in the cell to double
                        w = cellfun(@(x) str2double(x), weightsStr);      
                    catch
                        beep;
                        msgbox('Invalid weights data :(', 'Error','error');
                        app.ClassifyButton.Text = ClassifyButtonText;
                        app.ClassifyButton.Enable = 'on';
                        return;
                    end
                else
                    beep;
                    msgbox('Weight vector size must match data rows :(', 'Error','error');
                    app.ClassifyButton.Text = ClassifyButtonText;
                    app.ClassifyButton.Enable = 'on';
                    return;
                end
                classesTypes = app.cl.weightedKNNAsync(app.trainedSet, app.trainedSetClasses,...
                    testObjects, k, w);
            elseif app.BayesianButton.Value == true
                classesTypes = app.cl.bayesClassifyAsync(app.trainedBayes.baySet,...
                    app.trainedBayes.classes, app.trainedBayes.classesProps, testObjects, @normc);
            else%if app.SVMButton.Value == true
                classesTypes = app.cl.svmClassifyAsync(app.trainedSVM, testObjects);
            end
            
            I = app.UIAxes.Children.CData;
            for i=1:size(testObjects,1)
                % Show the location of the objects in the original image
                I = insertObjectAnnotation(I, 'rectangle', testObjectsPos{i},...
                    classesTypes{i},'TextBoxOpacity',0.5,'FontSize',14);
            end
            timeConsumed = toc;
            imshow(I, 'Parent', app.UIAxes);
            app.EllapsedTimeforthisMethodLabel_2.Visible = 'on';
            app.sLabel_2.Visible = 'on';
            if timeConsumed < 60
                timeConsumedSeconds = sprintf('%.2f', timeConsumed);
                app.sLabel_2.Text = strcat(num2str(timeConsumedSeconds),' sec');
            else
                timeConsumedMinutes = sprintf('%.2f', (timeConsumed/60)); 
                app.sLabel_2.Text = strcat(num2str(timeConsumedMinutes),' min');
            end   
            app.ClassifyButton.Text = ClassifyButtonText;
            app.ClassifyButton.Enable = 'on';
            pause(0.1); % wait for GUI to take effect
            %Play sound
            sound(app.doneSound.y, app.doneSound.Fs);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            %load(filename);
            [fName, pName] = uigetfile({'*.mat;'},'Select the trained file');
            if ~isequal(fName,0)
                fPath = strcat(pName,fName);
                try
                    s = load(fPath);
                    trainStruct = s.trainStruct;
                    app.trainType = trainStruct.trainType;
                    app.segmentSize = trainStruct.segmentSize;
                    app.trainedSet = trainStruct.trainedSet;
                    app.trainedSetClasses = trainStruct.trainedSetClasses;
                    app.trainedSVM = trainStruct.trainedSVM;
                    if isequal(app.trainedSVM, [])
                        app.SVMButton.Enable = 'off';
                    else
                        app.SVMButton.Enable = 'on';
                    end
                    app.trainedBayes = trainStruct.trainedBayes;
                    app.PleasefirstyouMUSTgotoTrainingTabtoBrowsetheDatLabel.Visible = 'off';
                    app.PleasefirstyouMUSTgotoTrainingTabtoBrowsetheDatLabel_2.Visible = 'off';
                    msgbox('Training data loaded succesfully :)', 'Done','help');
                catch
                    beep;
                    msgbox('Cannot load the training data :(', 'Error','error');
                end
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            try
                filename = strcat(datestr(now,'HH-MM-SS'), '_',date, '.mat');
                trainStruct.trainType = app.trainType;
                trainStruct.segmentSize = app.segmentSize;
                trainStruct.trainedSet = app.trainedSet;
                trainStruct.trainedSetClasses = app.trainedSetClasses;
                trainStruct.trainedSVM = app.trainedSVM;
                trainStruct.trainedBayes = app.trainedBayes;
                save(filename, 'trainStruct');
                msgbox('Training data saved succesfully :)', 'Done','help');
            catch
                beep;
                msgbox('Cannot save the training data :(', 'Error','error');
            end
        end

        % Button pushed function: EvaluateButton
        function EvaluateButtonPushed(app, event)
            app.EvaluateButton.Enable = 'off';
            app.NeuralNetworkButton.Enable = 'off';
            EvaluateButtonText = app.EvaluateButton.Text;
            app.EvaluateButton.Text = 'Please Wait...';
            
            try
                noiseThreshold = app.NoiseWidthEditField2.Value*app.NoiseHeightEditField2.Value;
                [dataClasses, imagePaths2D] = app.ir.read(app.TESTSETPATH);
                % train the test data to extract the features first
                if isequal(app.trainType, 'HOG')
                    [testSet, testSetClasses] = ...
                        app.cl.tr.TrainAsync(dataClasses, imagePaths2D, noiseThreshold, app.segmentSize, 1); % 1 = HOG
                else%if isequal(app.trainType, 'Custom')
                    [testSet, testSetClasses] = ...
                        app.cl.tr.TrainAsync(dataClasses, imagePaths2D, noiseThreshold, app.segmentSize, 0);
                end
                % start the classification process
                tic; %start stopwatch
                if app.WeightedkNNButton.Value == true
                    k = app.kSpinner.Value;
                    %Taking the text as a string then separating to chars (cell of chars)
                    weightsStr = strsplit(app.WeightsEditField.Value,',');
                    if numel(weightsStr) == size(app.trainedSet,2) || weightsStr{1} == '0'
                        try
                            %Converting each element in the cell to double
                            w = cellfun(@(x) str2double(x), weightsStr);      
                        catch
                            beep;
                            msgbox('Invalid weights data :(', 'Error','error');
                            app.EvaluateButton.Text = EvaluateButtonText;
                            app.EvaluateButton.Enable = 'on';
                            app.NeuralNetworkButton.Enable = 'on';
                            return;
                        end
                    else
                        beep;
                        msgbox('Weight vector size must match data rows :(', 'Error','error');
                        app.EvaluateButton.Text = EvaluateButtonText;
                        app.EvaluateButton.Enable = 'on';
                        app.NeuralNetworkButton.Enable = 'on';
                        return;
                    end
                    classesTypes = app.cl.weightedKNNAsync(app.trainedSet, app.trainedSetClasses,...
                        testSet, k, w);
                elseif app.BayesianButton.Value == true
                    classesTypes = app.cl.bayesClassifyAsync(app.trainedBayes.baySet,...
                        app.trainedBayes.classes, app.trainedBayes.classesProps, testSet, @normc);
                else%if app.SVMButton.Value == true
                    classesTypes = app.cl.svmClassifyAsync(app.trainedSVM, testSet);
                end
                elTime = toc;
                % validiate the classification results
                % x-> testSetClasses element
                % y-> classesTypes element
                % cellfun(@(x,y) isequal(x,y), testSetClasses, classesTypes) -> result in logical array
                countTrue = sum(cellfun(@(x,y) isequal(x,y), testSetClasses, classesTypes));
                countFalse = numel(classesTypes) - countTrue;
                errorRatio = (countFalse/(countTrue+countFalse))*100;
                accuracy = (countTrue/(countTrue+countFalse))*100;
                % set UITable data
                app.UITable.Data = [countTrue countFalse errorRatio accuracy elTime];
            catch
                beep;
                msgbox('Sorry, something went wrong :(', 'Invalid Data','error');
            end
            
            app.EvaluateButton.Text = EvaluateButtonText;
            app.EvaluateButton.Enable = 'on';
            app.NeuralNetworkButton.Enable = 'on';
            %Play sound
            sound(app.doneSound.y, app.doneSound.Fs);
        end

        % Button pushed function: BrowseTestsetButton
        function BrowseTestsetButtonPushed(app, event)
            folderPath = uigetdir('Select testset folder');
            if ~isequal(folderPath,0)
                app.TESTSETPATH = folderPath;
                app.EvaluateButton.Enable = 'on';
                app.NeuralNetworkButton.Enable = 'on';
            end
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            %Waiting while training
            WaitGUI(app); %This function takes finishTime as the second argument
            app.TrainButton.Enable = 'off';
            pause(0.1); % wait for GUI to take effect
            app.segmentSize = [app.SegmentWidthEditField.Value app.SegmentHeightEditField.Value];
            noiseThreshold = app.NoiseWidthEditField.Value*app.NoiseHeightEditField.Value;
            tic;
            [dataClasses, imagePaths2D] = app.ir.read(app.DATASETPATH);
            if app.HOGButton.Value == true && app.ParallelButton.Value == true
                app.trainType = 'HOG';
                [app.trainedSet, app.trainedSetClasses] = ...
                    app.cl.tr.TrainAsync(dataClasses, imagePaths2D, noiseThreshold, app.segmentSize, 1); % 1 = HOG
            elseif app.CustomButton.Value == true && app.ParallelButton.Value == true
                app.trainType = 'Custom';
                [app.trainedSet, app.trainedSetClasses] = ...
                    app.cl.tr.TrainAsync(dataClasses, imagePaths2D, noiseThreshold, app.segmentSize, 0);
             
            elseif app.HOGButton.Value == true && app.SequentialButton.Value == true
                app.trainType = 'HOG';
                [app.trainedSet, app.trainedSetClasses] = ...
                    app.cl.tr.TrainHOG(dataClasses, imagePaths2D, noiseThreshold, app.segmentSize);
            else%if app.CustomButton.Value == true && app.SequentialButton.Value == true
                app.trainType = 'Custom';
                [app.trainedSet, app.trainedSetClasses] = ...
                    app.cl.tr.Train(dataClasses, imagePaths2D, noiseThreshold, app.segmentSize);
            end
            [app.trainedBayes.baySet, app.trainedBayes.classes, app.trainedBayes.classesProps] = ...
                app.cl.bh.getBayesianSet(app.trainedSet, app.trainedSetClasses, @normc);
            try
                app.trainedSVM = app.cl.svmTrain(app.trainedSet, app.trainedSetClasses);
                app.SVMButton.Enable = 'on';
            catch
                msgbox('SVM: No covergence achieved after the maximum number of iterations!)',...
                    'Cannot use SVM','warn');
                app.SVMButton.Enable = 'off';
            end
            elTime = toc;
            %After training
            AfterWaitingGUI(app, elTime) %This function takes timeConsumed as the second
            ...argument AS SECONDS NOT MINUTES (it converts seconds to minutes if needed)
            app.PleasefirstyouMUSTgotoTrainingTabtoBrowsetheDatLabel.Visible = 'off';
            app.PleasefirstyouMUSTgotoTrainingTabtoBrowsetheDatLabel_2.Visible = 'off';
            app.TrainButton.Enable = 'on';
        end

        % Button pushed function: ViewlargerButton
        function ViewlargerButtonPushed(app, event)
            try
                imshow(app.UIAxes.Children.CData);
            catch
            end
        end

        % Button pushed function: NeuralNetworkButton
        function NeuralNetworkButtonPushed(app, event)
            app.EvaluateButton.Enable = 'off';
            app.NeuralNetworkButton.Enable = 'off';
            NeuralNetworkButtonText = app.NeuralNetworkButton.Text;
            app.NeuralNetworkButton.Text = 'Please Wait...';
            
            try
                noiseThreshold = app.NoiseWidthEditField2.Value*app.NoiseHeightEditField2.Value;
                [dataClasses, imagePaths2D] = app.ir.read(app.TESTSETPATH);
                % train the test data to extract the features first
                if isequal(app.trainType, 'HOG')
                    [testSet, testSetClasses] = ...
                        app.cl.tr.TrainAsync(dataClasses, imagePaths2D, noiseThreshold, app.segmentSize, 1); % 1 = HOG
                else%if isequal(app.trainType, 'Custom')
                    [testSet, testSetClasses] = ...
                        app.cl.tr.TrainAsync(dataClasses, imagePaths2D, noiseThreshold, app.segmentSize, 0);
                end
                % start the classification process
                tic; %start stopwatch
                if app.WeightedkNNButton.Value == true
                    k = app.kSpinner.Value;
                    %Taking the text as a string then separating to chars (cell of chars)
                    weightsStr = strsplit(app.WeightsEditField.Value,',');
                    if numel(weightsStr) == size(app.trainedSet,2) || weightsStr{1} == '0'
                        try
                            %Converting each element in the cell to double
                            w = cellfun(@(x) str2double(x), weightsStr);      
                        catch
                            beep;
                            msgbox('Invalid weights data :(', 'Error','error');
                            app.EvaluateButton.Enable = 'on';
                            app.NeuralNetworkButton.Text = NeuralNetworkButtonText;
                            app.NeuralNetworkButton.Enable = 'on';
                            return;
                        end
                    else
                        beep;
                        msgbox('Weight vector size must match data rows :(', 'Error','error');
                        app.EvaluateButton.Enable = 'on';
                        app.NeuralNetworkButton.Text = NeuralNetworkButtonText;
                        app.NeuralNetworkButton.Enable = 'on';
                        return;
                    end
                    classesTypes = app.cl.weightedKNNAsync(app.trainedSet, app.trainedSetClasses,...
                        testSet, k, w);
                elseif app.BayesianButton.Value == true
                    classesTypes = app.cl.bayesClassifyAsync(app.trainedBayes.baySet,...
                        app.trainedBayes.classes, app.trainedBayes.classesProps, testSet, @normc);
                else%if app.SVMButton.Value == true
                    classesTypes = app.cl.svmClassifyAsync(app.trainedSVM, testSet);
                end
                elTime = toc;
            catch
                
            end
            % validiate the classification results
            classesTypesDouble = cellfun(@(x) double(x) ,classesTypes);
            testSetClassesDouble = cellfun(@(x) double(x) ,testSetClasses);
            vectLn = numel(classesTypes);
            %hd5l three inputs a w b w c arkam 3shwa2ya 
            a= rot90(classesTypesDouble/1000);
            b= rot90(testSetClassesDouble/1000);
            predict = rand(size(classesTypesDouble));
            c= rot90(predict);
            
            n=rot90(predict)*0.05;  %n dh noise aw nsbt al error f kol input 3shan abyn en al inputs de real numbers 
            y=a*5+b.*c+7*c+n; %equation bdrb al weight fe kol input 3shan atl3 output 
            I=[a; b; c]; %7tet al inputs f variable 
            O=y; % w al y al hwa al output  fe variable 
            R=[0 1; 0 1 ; 0 1]; %bgeb al min w al max bta3 kol input w kda kda al output dymn bytl3 ya zero y one 3shan by3dy 3la threshold  
            S=[5 1]; %size of input=5 w al size of output=1
            net = newff([0 1;0 1 ;0 1],S,{'tansig','purelin'}); %function bulti in ghza 3shan a3ml create network
            net=train(net,I,O); %b3ml training ll network 
            O1=sim(net,I); %brsm al network 
            plot(1:vectLn,O,1:vectLn,O1);
            scatter(O,O1);
            
            
            app.EvaluateButton.Enable = 'on';
            app.NeuralNetworkButton.Text = NeuralNetworkButtonText;
            app.NeuralNetworkButton.Enable = 'on';
            %Play sound
            sound(app.doneSound.y, app.doneSound.Fs);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PatternProjectGUIUIFigure = uifigure;
            app.PatternProjectGUIUIFigure.Name = 'PatternProjectGUI';
            setAutoResize(app, app.PatternProjectGUIUIFigure, false)
            app.PatternRecognitionProjectLabel = uilabel(app.PatternProjectGUIUIFigure);
            app.PatternRecognitionProjectLabel.Text = 'Pattern Recognition Project';
            app.TabGroup = uitabgroup(app.PatternProjectGUIUIFigure);
            app.TrainingTab = uitab(app.TabGroup);
            app.TrainingTab.Title = 'Training';
            app.TrainingTab.Units = 'pixels';
            app.BrowseDatasetButton = uibutton(app.TrainingTab, 'push');
            app.BrowseDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseDatasetButtonPushed, true);
            app.BrowseDatasetButton.Text = 'Browse [Dataset]';
            app.PleasewaitwhileTrainingLabel = uilabel(app.TrainingTab);
            app.PleasewaitwhileTrainingLabel.Visible = 'off';
            app.PleasewaitwhileTrainingLabel.Text = 'Please wait while Training';
            app.Label = uilabel(app.TrainingTab);
            app.Label.Visible = 'off';
            app.Label.Text = '...';
            app.ChooseanexecutiontypeButtonGroup = uibuttongroup(app.TrainingTab);
            app.ChooseanexecutiontypeButtonGroup.Title = 'Choose an execution type :';
            app.SequentialButton = uiradiobutton(app.ChooseanexecutiontypeButtonGroup);
            app.SequentialButton.Text = 'Sequential';
            app.SequentialButton.Value = true;
            app.ParallelButton = uiradiobutton(app.ChooseanexecutiontypeButtonGroup);
            app.ParallelButton.Text = 'Parallel';
            app.SaveButton = uibutton(app.TrainingTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.TrainingTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.Lamp = uilamp(app.TrainingTab);
            app.Lamp.Visible = 'off';
            app.EllapsedTimeforthisMethodLabel = uilabel(app.TrainingTab);
            app.EllapsedTimeforthisMethodLabel.Visible = 'off';
            app.EllapsedTimeforthisMethodLabel.Text = 'Ellapsed Time for this Method: ';
            app.Lamp_2 = uilamp(app.TrainingTab);
            app.Lamp_2.Visible = 'off';
            app.ThanksforwaitingLabel = uilabel(app.TrainingTab);
            app.ThanksforwaitingLabel.Visible = 'off';
            app.ThanksforwaitingLabel.Text = 'Thanks for waiting :)';
            app.sLabel = uilabel(app.TrainingTab);
            app.sLabel.Visible = 'off';
            app.sLabel.Text = '30 s';
            app.FeaturestobeextractedButtonGroup = uibuttongroup(app.TrainingTab);
            app.FeaturestobeextractedButtonGroup.Title = 'Features to be extracted :';
            app.HOGButton = uiradiobutton(app.FeaturestobeextractedButtonGroup);
            app.HOGButton.Text = 'HOG';
            app.HOGButton.Value = true;
            app.CustomButton = uiradiobutton(app.FeaturestobeextractedButtonGroup);
            app.CustomButton.Text = 'Custom';
            app.TrainButton = uibutton(app.TrainingTab, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Enable = 'off';
            app.TrainButton.Text = 'Train';
            app.SegmentSizePanel = uipanel(app.TrainingTab);
            app.SegmentSizePanel.Title = 'Segment Size :';
            app.WidthEditFieldLabel = uilabel(app.SegmentSizePanel);
            app.WidthEditFieldLabel.Text = 'Width';
            app.SegmentWidthEditField = uieditfield(app.SegmentSizePanel, 'numeric');
            app.SegmentWidthEditField.Limits = [1 Inf];
            app.SegmentWidthEditField.Value = 8;
            app.HeightEditFieldLabel = uilabel(app.SegmentSizePanel);
            app.HeightEditFieldLabel.Text = 'Height';
            app.SegmentHeightEditField = uieditfield(app.SegmentSizePanel, 'numeric');
            app.SegmentHeightEditField.Limits = [1 Inf];
            app.SegmentHeightEditField.Value = 8;
            app.NoiseThresholdPanel = uipanel(app.TrainingTab);
            app.NoiseThresholdPanel.Title = 'Noise Threshold :';
            app.WidthEditField_2Label = uilabel(app.NoiseThresholdPanel);
            app.WidthEditField_2Label.Text = 'Width';
            app.NoiseWidthEditField = uieditfield(app.NoiseThresholdPanel, 'numeric');
            app.NoiseWidthEditField.Limits = [1 Inf];
            app.NoiseWidthEditField.Value = 120;
            app.HeightEditField_2Label = uilabel(app.NoiseThresholdPanel);
            app.HeightEditField_2Label.Text = 'Height';
            app.NoiseHeightEditField = uieditfield(app.NoiseThresholdPanel, 'numeric');
            app.NoiseHeightEditField.Limits = [1 Inf];
            app.NoiseHeightEditField.Value = 120;
            app.ClassificationTab = uitab(app.TabGroup);
            app.ClassificationTab.Title = 'Classification';
            app.ClassificationTab.Units = 'pixels';
            app.ChooseanalgorithmButtonGroup = uibuttongroup(app.ClassificationTab);
            app.ChooseanalgorithmButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChooseanalgorithmButtonGroupSelectionChanged, true);
            app.ChooseanalgorithmButtonGroup.Title = 'Choose an algorithm :';
            app.WeightedkNNButton = uiradiobutton(app.ChooseanalgorithmButtonGroup);
            app.WeightedkNNButton.Text = 'Weighted kNN';
            app.WeightedkNNButton.Value = true;
            app.kSpinnerLabel = uilabel(app.ChooseanalgorithmButtonGroup);
            app.kSpinnerLabel.Text = 'k : ';
            app.kSpinner = uispinner(app.ChooseanalgorithmButtonGroup);
            app.kSpinner.Limits = [0 9];
            app.kSpinner.ValueDisplayFormat = '%.0f';
            app.EnterweightsSEPARATEDbycommaLabel = uilabel(app.ChooseanalgorithmButtonGroup);
            app.EnterweightsSEPARATEDbycommaLabel.Text = '**Enter weights SEPARATED by comma '',''';
            app.WeightsLabel = uilabel(app.ChooseanalgorithmButtonGroup);
            app.WeightsLabel.Text = 'Weights **';
            app.WeightsEditField = uieditfield(app.ChooseanalgorithmButtonGroup, 'text');
            app.WeightsEditField.Value = '0';
            app.SVMButton = uiradiobutton(app.ChooseanalgorithmButtonGroup);
            app.SVMButton.Text = 'SVM';
            app.BayesianButton = uiradiobutton(app.ChooseanalgorithmButtonGroup);
            app.BayesianButton.Text = 'Bayesian';
            app.UIAxes = uiaxes(app.ClassificationTab);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.BrowseImageButton = uibutton(app.ClassificationTab, 'push');
            app.BrowseImageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseImageButtonPushed, true);
            app.BrowseImageButton.Text = 'Browse Image';
            app.ClassifyButton = uibutton(app.ClassificationTab, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Enable = 'off';
            app.ClassifyButton.Text = 'Classify';
            app.XLabel = uilabel(app.ClassificationTab);
            app.XLabel.Visible = 'off';
            app.XLabel.Text = 'X';
            app.ViewlargerButton = uibutton(app.ClassificationTab, 'push');
            app.ViewlargerButton.ButtonPushedFcn = createCallbackFcn(app, @ViewlargerButtonPushed, true);
            app.ViewlargerButton.Enable = 'off';
            app.ViewlargerButton.Text = 'View larger';
            app.NoiseThresholdPanel2 = uipanel(app.ClassificationTab);
            app.NoiseThresholdPanel2.Title = 'Noise Threshold :';
            app.NoiseWidthEditField_2Label = uilabel(app.NoiseThresholdPanel2);
            app.NoiseWidthEditField_2Label.Text = 'Width';
            app.NoiseWidthEditField2 = uieditfield(app.NoiseThresholdPanel2, 'numeric');
            app.NoiseWidthEditField2.Limits = [1 Inf];
            app.NoiseWidthEditField2.Value = 10;
            app.NoiseHeightEditField_2Label = uilabel(app.NoiseThresholdPanel2);
            app.NoiseHeightEditField_2Label.Text = 'Height';
            app.NoiseHeightEditField2 = uieditfield(app.NoiseThresholdPanel2, 'numeric');
            app.NoiseHeightEditField2.Limits = [1 Inf];
            app.NoiseHeightEditField2.Value = 10;
            app.PleasefirstyouMUSTgotoTrainingTabtoBrowsetheDatLabel = uilabel(app.ClassificationTab);
            app.PleasefirstyouMUSTgotoTrainingTabtoBrowsetheDatLabel.Enable = 'off';
            app.PleasefirstyouMUSTgotoTrainingTabtoBrowsetheDatLabel.Text = {'Please, First you MUST go '; 'to Training Tab to Browse '; 'the Dataset, then Choose'; 'a Method to train'};
            app.EllapsedTimeforthisMethodLabel_2 = uilabel(app.ClassificationTab);
            app.EllapsedTimeforthisMethodLabel_2.Visible = 'off';
            app.EllapsedTimeforthisMethodLabel_2.Text = 'Ellapsed Time for this Method: ';
            app.sLabel_2 = uilabel(app.ClassificationTab);
            app.sLabel_2.Visible = 'off';
            app.sLabel_2.Text = '30 s';
            app.EvaluationTab = uitab(app.TabGroup);
            app.EvaluationTab.Title = 'Evaluation';
            app.EvaluationTab.Units = 'pixels';
            app.UITable = uitable(app.EvaluationTab);
            app.UITable.ColumnName = {'# Right'; '# Wrong'; 'Error Ratio(%)'; 'Accuracy(%)'; 'Ellapsed Time(sec)'};
            app.UITable.RowName = {};
            app.BrowseTestsetButton = uibutton(app.EvaluationTab, 'push');
            app.BrowseTestsetButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseTestsetButtonPushed, true);
            app.BrowseTestsetButton.Text = 'Browse Testset';
            app.EvaluateButton = uibutton(app.EvaluationTab, 'push');
            app.EvaluateButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateButtonPushed, true);
            app.EvaluateButton.Enable = 'off';
            app.EvaluateButton.Text = 'Evaluate';
            app.NeuralNetworkButton = uibutton(app.EvaluationTab, 'push');
            app.NeuralNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @NeuralNetworkButtonPushed, true);
            app.NeuralNetworkButton.Enable = 'off';
            app.NeuralNetworkButton.Text = 'Neural Network';
            app.PleasefirstyouMUSTgotoTrainingTabtoBrowsetheDatLabel_2 = uilabel(app.EvaluationTab);
            app.PleasefirstyouMUSTgotoTrainingTabtoBrowsetheDatLabel_2.Enable = 'off';
            app.PleasefirstyouMUSTgotoTrainingTabtoBrowsetheDatLabel_2.Text = {'Please, First you MUST go '; 'to Training Tab to Browse '; 'the Dataset, then Choose'; 'a Method to train'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = PatternProjectGUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PatternProjectGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PatternProjectGUIUIFigure)
        end
    end
end